classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TrocarButton                   matlab.ui.control.Button
        AbrirButton                    matlab.ui.control.Button
        TipoGraficoListBox             matlab.ui.control.ListBox
        TipoGraficoListBoxLabel        matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PassaBaixaTab                  matlab.ui.container.Tab
        ParmetrosdofiltropassabaixaLabel  matlab.ui.control.Label
        ExecutarButton                 matlab.ui.control.Button
        JanelamentosListBox            matlab.ui.control.ListBox
        JanelamentosListBoxLabel       matlab.ui.control.Label
        BandaderejeioEditField         matlab.ui.control.NumericEditField
        BandaderejeioEditFieldLabel    matlab.ui.control.Label
        BandadepassagemEditField       matlab.ui.control.NumericEditField
        BandadepassagemEditFieldLabel  matlab.ui.control.Label
        AtenuaoLabel                   matlab.ui.control.Label
        LarguradetransiioEditField     matlab.ui.control.NumericEditField
        LarguradetransiioEditFieldLabel  matlab.ui.control.Label
        FaixadepassagemEditField       matlab.ui.control.NumericEditField
        FaixadepassagemEditFieldLabel  matlab.ui.control.Label
        PassaAltaTab                   matlab.ui.container.Tab
        ParmetrosdofiltropassaaltaLabel  matlab.ui.control.Label
        JanelamentosListBox_2          matlab.ui.control.ListBox
        JanelamentosListBox_2Label     matlab.ui.control.Label
        BandaderejeioEditField_2       matlab.ui.control.NumericEditField
        BandaderejeioEditField_2Label  matlab.ui.control.Label
        BandadepassagemEditField_2     matlab.ui.control.NumericEditField
        BandadepassagemEditField_2Label  matlab.ui.control.Label
        sEditField_2                   matlab.ui.control.NumericEditField
        sEditField_2Label              matlab.ui.control.Label
        pEditField_2                   matlab.ui.control.NumericEditField
        pEditField_2Label              matlab.ui.control.Label
        ExecutarButton_2               matlab.ui.control.Button
        AtenuaoLabel_2                 matlab.ui.control.Label
        PassaFaixaTab                  matlab.ui.container.Tab
        ParmetrosdofiltropassafaixaLabel  matlab.ui.control.Label
        RejeitaFaixaTab                matlab.ui.container.Tab
        ParmetrosdofiltrorejeitafaixaLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        M; % Description
        w;
        W;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function chama_passabaixa(app, event)
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            app.M = 51;
            
            
            switch(getGrafico(1))
                case 1
                    title (app.UIAxes,"Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis(app.UIAxes, [0 (app.M-1) 0 1.1]);
                case 2
                    title (app.UIAxes,"Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end


            getJanelamento = get(app.JanelamentosListBox,'Value');


            switch(getJanelamento(1))
                case 1
                    [app.w,app.W] = rectangle_pbl(app.M);
                case 2
                    [app.w,app.W] = bartlett_pbl(app.M);
                case 3
                    [app.w,app.W] = hann_pbl(app.M);
                case 4
                    [app.w,app.W] = hamming_pbl(app.M);
                case 5
                    [app.w,app.W] = blackman_pbl(app.M);
            end
            
            if(getGrafico(1) == 2)
                
                w2 = fft(app.w);
                
                plot(app.UIAxes, abs(w2))
            else
                stem(app.UIAxes,app.W,app.w);
            end


        end

        % Button pushed function: TrocarButton
        function trocarGrafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title (app.UIAxes,"Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis(app.UIAxes, [0 (app.M-1) 0 1.1]);
                case 2
                    title (app.UIAxes,"Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end
            
            if(getGrafico(1) == 2)
                
                v = 0:app.M;
                
                [f,w2] = my_fft2(v,app.w, 8000);
                
                plot(app.UIAxes, f,w2);
                
            else
                stem(app.UIAxes,app.W,app.w);
            end
            
        end

        % Button pushed function: AbrirButton
        function abrir_grafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title ("Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis([0 (app.M-1) 0 1.1]);
                case 2
                    title ("Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end
            
            if(getGrafico(1) == 2)
                
                w2 = fft(app.w);
                
                plot(app.W, abs(w2))
            else
                stem(app.W,app.w);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vazio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PassaBaixaTab = uitab(app.TabGroup);
            app.PassaBaixaTab.Title = 'Passa Baixa';
            app.FaixadepassagemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FaixadepassagemEditFieldLabel.Text = 'Faixa de passagem';
            app.FaixadepassagemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.LarguradetransiioEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.LarguradetransiioEditFieldLabel.Text = 'Largura de transiição';
            app.LarguradetransiioEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.AtenuaoLabel = uilabel(app.PassaBaixaTab);
            app.AtenuaoLabel.Text = 'Atenuação:';
            app.BandadepassagemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.BandadepassagemEditFieldLabel.Text = 'Banda de passagem';
            app.BandadepassagemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.BandaderejeioEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.BandaderejeioEditFieldLabel.Text = 'Banda de rejeição';
            app.BandaderejeioEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.JanelamentosListBoxLabel = uilabel(app.PassaBaixaTab);
            app.JanelamentosListBoxLabel.Text = 'Janelamentos';
            app.JanelamentosListBox = uilistbox(app.PassaBaixaTab);
            app.JanelamentosListBox.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox.ItemsData = [1 2 3 4 5];
            app.JanelamentosListBox.Value = 1;
            app.ExecutarButton = uibutton(app.PassaBaixaTab, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @chama_passabaixa, true);
            app.ExecutarButton.Text = 'Executar';
            app.ParmetrosdofiltropassabaixaLabel = uilabel(app.PassaBaixaTab);
            app.ParmetrosdofiltropassabaixaLabel.Text = 'Parâmetros do filtro passa baixa:';
            app.PassaAltaTab = uitab(app.TabGroup);
            app.PassaAltaTab.Title = 'Passa Alta';
            app.AtenuaoLabel_2 = uilabel(app.PassaAltaTab);
            app.AtenuaoLabel_2.Text = 'Atenuação:';
            app.ExecutarButton_2 = uibutton(app.PassaAltaTab, 'push');
            app.ExecutarButton_2.Text = 'Executar';
            app.pEditField_2Label = uilabel(app.PassaAltaTab);
            app.pEditField_2Label.Text = 'ωp';
            app.pEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.sEditField_2Label = uilabel(app.PassaAltaTab);
            app.sEditField_2Label.Text = 'ωs';
            app.sEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.BandadepassagemEditField_2Label = uilabel(app.PassaAltaTab);
            app.BandadepassagemEditField_2Label.Text = 'Banda de passagem';
            app.BandadepassagemEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.BandaderejeioEditField_2Label = uilabel(app.PassaAltaTab);
            app.BandaderejeioEditField_2Label.Text = 'Banda de rejeição';
            app.BandaderejeioEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.JanelamentosListBox_2Label = uilabel(app.PassaAltaTab);
            app.JanelamentosListBox_2Label.Text = 'Janelamentos';
            app.JanelamentosListBox_2 = uilistbox(app.PassaAltaTab);
            app.JanelamentosListBox_2.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox_2.ItemsData = [1 2 3 4 5];
            app.JanelamentosListBox_2.Value = 1;
            app.ParmetrosdofiltropassaaltaLabel = uilabel(app.PassaAltaTab);
            app.ParmetrosdofiltropassaaltaLabel.Text = 'Parâmetros do filtro passa alta:';
            app.PassaFaixaTab = uitab(app.TabGroup);
            app.PassaFaixaTab.Title = 'Passa Faixa';
            app.ParmetrosdofiltropassafaixaLabel = uilabel(app.PassaFaixaTab);
            app.ParmetrosdofiltropassafaixaLabel.Text = 'Parâmetros do filtro passa faixa:';
            app.RejeitaFaixaTab = uitab(app.TabGroup);
            app.RejeitaFaixaTab.Title = 'Rejeita Faixa';
            app.ParmetrosdofiltrorejeitafaixaLabel = uilabel(app.RejeitaFaixaTab);
            app.ParmetrosdofiltrorejeitafaixaLabel.Text = 'Parâmetros do filtro rejeita faixa:';
            app.TipoGraficoListBoxLabel = uilabel(app.UIFigure);
            app.TipoGraficoListBoxLabel.Text = {'Tipo'; 'Grafico'};
            app.TipoGraficoListBox = uilistbox(app.UIFigure);
            app.TipoGraficoListBox.Items = {'Janela', 'Magnitudade e Fase', 'Item 3', 'Item 4'};
            app.TipoGraficoListBox.ItemsData = [1 2 3 4];
            app.TipoGraficoListBox.Value = 1;
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @abrir_grafico, true);
            app.AbrirButton.Enable = 'off';
            app.AbrirButton.Text = 'Abrir';
            app.TrocarButton = uibutton(app.UIFigure, 'push');
            app.TrocarButton.ButtonPushedFcn = createCallbackFcn(app, @trocarGrafico, true);
            app.TrocarButton.Enable = 'off';
            app.TrocarButton.Text = 'Trocar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end